classdef MorseCodeTranslator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MorsetoTextTab             matlab.ui.container.Tab
        PlayMorseCodeLabel         matlab.ui.control.Label
        REPEATButton               matlab.ui.control.Button
        PAUSEButton                matlab.ui.control.Button
        PLAYButton                 matlab.ui.control.Button
        OutputEditField            matlab.ui.control.EditField
        OutputEditFieldLabel       matlab.ui.control.Label
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        SeparatorButton            matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        TranslateButton            matlab.ui.control.Button
        MorsetoTextEditField       matlab.ui.control.EditField
        MorsetoTextEditFieldLabel  matlab.ui.control.Label
        TexttoMorseTab             matlab.ui.container.Tab
        PlayMorseCodeLabel_2       matlab.ui.control.Label
        SPACEButton                matlab.ui.control.Button
        Button_17                  matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        Button_16                  matlab.ui.control.Button
        Button_14                  matlab.ui.control.Button
        Button_13                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        Button_11                  matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        REPEATButton_2             matlab.ui.control.Button
        PAUSEButton_2              matlab.ui.control.Button
        PLAYButton_2               matlab.ui.control.Button
        MButton                    matlab.ui.control.Button
        NButton                    matlab.ui.control.Button
        BButton                    matlab.ui.control.Button
        VButton                    matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        LButton                    matlab.ui.control.Button
        KButton                    matlab.ui.control.Button
        JButton                    matlab.ui.control.Button
        HButton                    matlab.ui.control.Button
        GButton_2                  matlab.ui.control.Button
        FButton                    matlab.ui.control.Button
        DButton                    matlab.ui.control.Button
        SButton                    matlab.ui.control.Button
        AButton                    matlab.ui.control.Button
        PButton                    matlab.ui.control.Button
        OButton                    matlab.ui.control.Button
        IButton                    matlab.ui.control.Button
        UButton                    matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        TButton                    matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        EButton                    matlab.ui.control.Button
        WButton                    matlab.ui.control.Button
        QButton                    matlab.ui.control.Button
        OutputEditField_2          matlab.ui.control.EditField
        OutputEditField_2Label     matlab.ui.control.Label
        Button_7                   matlab.ui.control.Button
        ResetButton_2              matlab.ui.control.Button
        TranslateButton_2          matlab.ui.control.Button
        TexttoMorseEditField       matlab.ui.control.EditField
        TexttoMorseEditFieldLabel  matlab.ui.control.Label
        MorseCodeChartTab          matlab.ui.container.Tab
        Label_37                   matlab.ui.control.Label
        Button_28                  matlab.ui.control.Button
        Label_36                   matlab.ui.control.Label
        Label_35                   matlab.ui.control.Label
        Label_34                   matlab.ui.control.Label
        Label_33                   matlab.ui.control.Label
        Label_32                   matlab.ui.control.Label
        Label_31                   matlab.ui.control.Label
        Label_30                   matlab.ui.control.Label
        Label_29                   matlab.ui.control.Label
        Label_28                   matlab.ui.control.Label
        Label_26                   matlab.ui.control.Label
        Label_25                   matlab.ui.control.Label
        Label_24                   matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Button_27                  matlab.ui.control.Button
        Button_26                  matlab.ui.control.Button
        Button_25                  matlab.ui.control.Button
        Button_24                  matlab.ui.control.Button
        Button_23                  matlab.ui.control.Button
        Button_22                  matlab.ui.control.Button
        Button_21                  matlab.ui.control.Button
        Button_20                  matlab.ui.control.Button
        Button_19                  matlab.ui.control.Button
        ZButton_2                  matlab.ui.control.Button
        YButton_2                  matlab.ui.control.Button
        XButton_2                  matlab.ui.control.Button
        WButton_2                  matlab.ui.control.Button
        VButton_2                  matlab.ui.control.Button
        UButton_2                  matlab.ui.control.Button
        TButton_2                  matlab.ui.control.Button
        SButton_2                  matlab.ui.control.Button
        RButton_2                  matlab.ui.control.Button
        QButton_2                  matlab.ui.control.Button
        PButton_2                  matlab.ui.control.Button
        OButton_2                  matlab.ui.control.Button
        NButton_2                  matlab.ui.control.Button
        MButton_2                  matlab.ui.control.Button
        LButton_2                  matlab.ui.control.Button
        KButton_2                  matlab.ui.control.Button
        JButton_2                  matlab.ui.control.Button
        IButton_2                  matlab.ui.control.Button
        HButton_2                  matlab.ui.control.Button
        Label_7                    matlab.ui.control.Label
        GButton_3                  matlab.ui.control.Button
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        FButton_2                  matlab.ui.control.Button
        EButton_2                  matlab.ui.control.Button
        DButton_2                  matlab.ui.control.Button
        CButton_2                  matlab.ui.control.Button
        BButton_2                  matlab.ui.control.Button
        AButton_2                  matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        player; 
        isPaused = false; 
        morseCode; 
        currentIdx = 1; 
        gapDuration = 0.005; 
    end


    methods (Access = private)
        function playMorseCode(app)
            if isempty(app.morseCode)
                return;
            end
        
            
            app.player = [];
        
            for i = app.currentIdx:length(app.morseCode)
                character = app.morseCode(i);
                if isletter(character) || isstrprop(character, 'digit')
                    [y, fs] = audioread([character '.wav']);
                    app.player = audioplayer(y, fs);
                    playblocking(app.player);
                    pause(app.gapDuration); 
                else
                    pause(app.gapDuration); 
                end
            
                if app.isPaused
                    app.currentIdx = i; 
                    break;
                end
            end
        
           
            if ~app.isPaused
                app.currentIdx = 1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            soundFile = 'dot.wav';

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);  

            currentText = app.MorsetoTextEditField.Value;
            app.MorsetoTextEditField.Value = [currentText '• '];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            soundFile = 'dash.wav';

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);  

            currentText = app.MorsetoTextEditField.Value;
            app.MorsetoTextEditField.Value = [currentText '━ '];  
        end

        % Button pushed function: SeparatorButton
        function SeparatorButtonPushed(app, event)
            currentText = app.MorsetoTextEditField.Value;
            app.MorsetoTextEditField.Value = [currentText '/ '];           
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            currentText = app.MorsetoTextEditField.Value;
            if ~isempty(currentText)

                app.MorsetoTextEditField.Value = currentText(1:end-2);
            end           
        end

        % Button pushed function: QButton
        function QButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'Q'];   
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'W'];   
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'E'];   
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'R'];   
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'T'];   
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'Y'];   
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'U'];   
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'I'];   
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'O'];   
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'P'];   
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'A'];   
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'S'];   
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'D'];   
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'F'];   
        end

        % Button pushed function: GButton_2
        function GButton_2Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'G'];   
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'H'];   
        end

        % Button pushed function: JButton
        function JButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'J'];   
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'K'];   
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'L'];   
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'Z'];   
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'X'];   
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'C'];   
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'V'];   
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'B'];   
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'N'];   
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText 'M'];   
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            if ~isempty(currentText)
                % Remove the last character or sequence (in this case, including the space after it)
                app.TexttoMorseEditField.Value = currentText(1:end-1);
            end       
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '1'];               
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '2'];   
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '3'];   
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '4'];   
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '5'];   
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '6'];   
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '7'];   
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '8'];   
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '9'];   
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText '0'];   
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            morseToTextMap = containers.Map( ...
                {'• ━ ', '━ • • • ', '━ • ━ • ', '━ • • ', '• ', '• • ━ • ', '━ ━ • ', '• • • • ', '• • ', '• ━ ━ ━ ', ...
                '━ • ━ ', '• ━ • • ', '━ ━ ', '━ • ', '━ ━ ━ ', '• ━ ━ • ', '━ ━ • ━ ', '• ━ • ', '• • • ', '━ ', '• • ━ ', ...
                '• • • ━ ', '• ━ ━ ', '━ • • ━ ', '━ • ━ ━ ', '━ ━ • • ', '━ ━ ━ ━ ━ ', '• ━ ━ ━ ━ ', '• • ━ ━ ━ ', ...
                '• • • ━ ━ ', '• • • • ━ ', '• • • • • ', '━ • • • • ', '━ ━ • • • ', '━ ━ ━ • • ', '━ ━ ━ ━ • '}, ...
                {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', ...
                'V', 'W', 'X', 'Y', 'Z', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9'});

            morseCodeInput = app.MorsetoTextEditField.Value;
    
            words = strsplit(morseCodeInput, '/ / ');
    
            translatedText = ' ';
    
            for i = 1:length(words)
                morseLetters = strsplit(words{i}, '/ ');
        
                for j = 1:length(morseLetters)
                    morseLetter = morseLetters{j};
                    if isKey(morseToTextMap, morseLetter)
                        translatedText = [translatedText morseToTextMap(morseLetter)];
                    else
                        translatedText = [translatedText '?'];
                    end
                end
        
                if i < length(words)
                    translatedText = [translatedText ' '];
                end
            end
    
            app.OutputEditField.Value = translatedText;
        end

        % Button pushed function: TranslateButton_2
        function TranslateButton_2Pushed(app, event)
            textToMorseMap = containers.Map(...
                {'A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S',...
                'T','U','V','W','X','Y','Z','0','1','2','3','4','5','6','7','8','9'}, ...
                {'• ━ ','━ • • • ','━ • ━ • ','━ • • ','• ','• • ━ • ','━ ━ • ','• • • • ','• • ','• ━ ━ ━ ',...
                '━ • ━ ','• ━ • • ','━ ━ ','━ • ','━ ━ ━ ','• ━ ━ • ','━ ━ • ━ ','• ━ • ','• • • ','━ ','• • ━ ','• • • ━ ',...
                '• ━ ━ ','━ • • ━ ','━ • ━ ━ ','━ ━ • • ','━ ━ ━ ━ ━ ','• ━ ━ ━ ━ ','• • ━ ━ ━ ','• • • ━ ━ ','• • • • ━ ','• • • • • ',...
                '━ • • • • ','━ ━ • • • ','━ ━ ━ • • ','━ ━ ━ ━ • '}...
                );

            inputText = upper(app.TexttoMorseEditField.Value);
            translatedMorseCode = '';

            for i = 1:length(inputText)
                character = inputText(i);
                if isKey(textToMorseMap, character)
                    translatedMorseCode = [translatedMorseCode textToMorseMap(character) '/ '];
                elseif character == ' '
                    translatedMorseCode = [translatedMorseCode '/ '];
                else
                    translatedMorseCode = [translatedMorseCode '?/'];
                end
            end

            if endsWith(translatedMorseCode, '/ ')
                translatedMorseCode = translatedMorseCode(1:end-2);
            end

            app.OutputEditField_2.Value = translatedMorseCode; 
        end

        % Button pushed function: SPACEButton
        function SPACEButtonPushed(app, event)
            currentText = app.TexttoMorseEditField.Value;
            app.TexttoMorseEditField.Value = [currentText ' '];            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end

            app.isPaused = true;
            app.currentIdx = 1;

            app.MorsetoTextEditField.Value = '';
            app.OutputEditField.Value = '';           
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end

            app.isPaused = true;
            app.currentIdx = 1;
            
            app.TexttoMorseEditField.Value = '';
            app.OutputEditField_2.Value = ''; 
        end

        % Button pushed function: AButton_2
        function AButton_2Pushed(app, event)
            soundFile = 'A.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);
        end

        % Button pushed function: BButton_2
        function BButton_2Pushed(app, event)
            soundFile = 'B.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            soundFile = 'C.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);
        end

        % Button pushed function: DButton_2
        function DButton_2Pushed(app, event)
            soundFile = 'D.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);            
        end

        % Button pushed function: EButton_2
        function EButton_2Pushed(app, event)
            soundFile = 'E.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);            
        end

        % Button pushed function: FButton_2
        function FButton_2Pushed(app, event)
            soundFile = 'F.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);            
        end

        % Button pushed function: GButton_3
        function GButton_3Pushed(app, event)
            soundFile = 'G.wav'; 

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);            
        end

        % Button pushed function: HButton_2
        function HButton_2Pushed(app, event)
            soundFile = 'H.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: IButton_2
        function IButton_2Pushed(app, event)
            soundFile = 'I.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: JButton_2
        function JButton_2Pushed(app, event)
            soundFile = 'J.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: KButton_2
        function KButton_2Pushed(app, event)
            soundFile = 'K.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: LButton_2
        function LButton_2Pushed(app, event)
            soundFile = 'L.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: MButton_2
        function MButton_2Pushed(app, event)
            soundFile = 'M.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);           
        end

        % Button pushed function: NButton_2
        function NButton_2Pushed(app, event)
            soundFile = 'N.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: OButton_2
        function OButton_2Pushed(app, event)
            soundFile = 'O.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            soundFile = 'P.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: QButton_2
        function QButton_2Pushed(app, event)
            soundFile = 'Q.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            soundFile = 'R.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: SButton_2
        function SButton_2Pushed(app, event)
            soundFile = 'S.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: TButton_2
        function TButton_2Pushed(app, event)
            soundFile = 'T.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: UButton_2
        function UButton_2Pushed(app, event)
            soundFile = 'U.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: VButton_2
        function VButton_2Pushed(app, event)
            soundFile = 'V.wav';

            [y, Fs] = audioread(soundFile);

            sound(y, Fs);
        end

        % Button pushed function: WButton_2
        function WButton_2Pushed(app, event)
            soundFile = 'W.wav';

            [y, Fs] = audioread(soundFile);
            
            sound(y, Fs);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            soundFile = 'X.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            soundFile = 'Y.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            soundFile = 'Z.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            soundFile = '1.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            soundFile = '2.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            soundFile = '3.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            soundFile = '4.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            soundFile = '5.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            soundFile = '6.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            soundFile = '7.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            soundFile = '8.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            soundFile = '9.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            soundFile = '0.wav';

            [y, Fs] = audioread(soundFile); 

            sound(y, Fs);            
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(app, event)
            if app.isPaused
                app.isPaused = false;
                playMorseCode(app);
            else
                app.morseCode = app.TexttoMorseEditField.Value;
                app.currentIdx = 1;
                playMorseCode(app);
            end
        end

        % Button pushed function: PAUSEButton_2
        function PAUSEButton_2Pushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
                app.isPaused = true;
            end
        end

        % Button pushed function: REPEATButton_2
        function REPEATButton_2Pushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end

            app.isPaused = false;
            app.currentIdx = 1;
            playMorseCode(app);
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            if app.isPaused
                app.isPaused = false;
                playMorseCode(app);
            else
                app.morseCode = app.OutputEditField.Value;
                app.currentIdx = 1;
                playMorseCode(app);
            end       
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
                app.isPaused = true;
            end          
        end

        % Button pushed function: REPEATButton
        function REPEATButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end

            app.isPaused = false;
            app.currentIdx = 1;
            playMorseCode(app);          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MorsetoTextTab = uitab(app.TabGroup);
            app.MorsetoTextTab.Title = 'Morse-to-Text';
            app.MorsetoTextEditFieldLabel = uilabel(app.MorsetoTextTab);
            app.MorsetoTextEditFieldLabel.Text = 'Morse-to-Text';
            app.MorsetoTextEditField = uieditfield(app.MorsetoTextTab, 'text');
            app.MorsetoTextEditField.Placeholder = 'Enter Morse Code ( / letters ) ( // words )';
            app.TranslateButton = uibutton(app.MorsetoTextTab, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.ResetButton = uibutton(app.MorsetoTextTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Button_6 = uibutton(app.MorsetoTextTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '⌫';
            app.SeparatorButton = uibutton(app.MorsetoTextTab, 'push');
            app.SeparatorButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatorButtonPushed, true);
            app.SeparatorButton.Text = '/ Separator';
            app.Button_2 = uibutton(app.MorsetoTextTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '━ ';
            app.Button_3 = uibutton(app.MorsetoTextTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '⚫';
            app.OutputEditFieldLabel = uilabel(app.MorsetoTextTab);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.MorsetoTextTab, 'text');
            app.OutputEditField.Placeholder = 'Morse-to-Text';
            app.PLAYButton = uibutton(app.MorsetoTextTab, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = '▶ PLAY';
            app.PAUSEButton = uibutton(app.MorsetoTextTab, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Text = '❚❚ PAUSE';
            app.REPEATButton = uibutton(app.MorsetoTextTab, 'push');
            app.REPEATButton.ButtonPushedFcn = createCallbackFcn(app, @REPEATButtonPushed, true);
            app.REPEATButton.Text = '⟲ REPEAT';
            app.PlayMorseCodeLabel = uilabel(app.MorsetoTextTab);
            app.PlayMorseCodeLabel.Text = 'Play Morse Code:';
            app.TexttoMorseTab = uitab(app.TabGroup);
            app.TexttoMorseTab.Title = 'Text-to-Morse';
            app.TexttoMorseEditFieldLabel = uilabel(app.TexttoMorseTab);
            app.TexttoMorseEditFieldLabel.Text = 'Text-to-Morse';
            app.TexttoMorseEditField = uieditfield(app.TexttoMorseTab, 'text');
            app.TexttoMorseEditField.Placeholder = 'Enter Text';
            app.TranslateButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.TranslateButton_2.ButtonPushedFcn = createCallbackFcn(app, @TranslateButton_2Pushed, true);
            app.TranslateButton_2.Text = 'Translate';
            app.ResetButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Button_7 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '⌫';
            app.OutputEditField_2Label = uilabel(app.TexttoMorseTab);
            app.OutputEditField_2Label.Text = 'Output';
            app.OutputEditField_2 = uieditfield(app.TexttoMorseTab, 'text');
            app.OutputEditField_2.Placeholder = 'Text-to-Morse ( / letters ) ( // words )';
            app.QButton = uibutton(app.TexttoMorseTab, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.QButton.Text = 'Q';
            app.WButton = uibutton(app.TexttoMorseTab, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.EButton = uibutton(app.TexttoMorseTab, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.RButton = uibutton(app.TexttoMorseTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.TButton = uibutton(app.TexttoMorseTab, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.YButton = uibutton(app.TexttoMorseTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.UButton = uibutton(app.TexttoMorseTab, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.IButton = uibutton(app.TexttoMorseTab, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = 'I';
            app.OButton = uibutton(app.TexttoMorseTab, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.PButton = uibutton(app.TexttoMorseTab, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.AButton = uibutton(app.TexttoMorseTab, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.TexttoMorseTab, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.TexttoMorseTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.TexttoMorseTab, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.GButton_2.ButtonPushedFcn = createCallbackFcn(app, @GButton_2Pushed, true);
            app.GButton_2.Text = 'G';
            app.HButton = uibutton(app.TexttoMorseTab, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.JButton = uibutton(app.TexttoMorseTab, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.TexttoMorseTab, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.TexttoMorseTab, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.ZButton = uibutton(app.TexttoMorseTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.XButton = uibutton(app.TexttoMorseTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.CButton = uibutton(app.TexttoMorseTab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.VButton = uibutton(app.TexttoMorseTab, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.BButton = uibutton(app.TexttoMorseTab, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.NButton = uibutton(app.TexttoMorseTab, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.MButton = uibutton(app.TexttoMorseTab, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.PLAYButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.PLAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_2Pushed, true);
            app.PLAYButton_2.Text = '▶ PLAY';
            app.PAUSEButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.PAUSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButton_2Pushed, true);
            app.PAUSEButton_2.Text = '❚❚ PAUSE';
            app.REPEATButton_2 = uibutton(app.TexttoMorseTab, 'push');
            app.REPEATButton_2.ButtonPushedFcn = createCallbackFcn(app, @REPEATButton_2Pushed, true);
            app.REPEATButton_2.Text = '⟲ REPEAT';
            app.Button_8 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '1';
            app.Button_9 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '2';
            app.Button_10 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '3';
            app.Button_11 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '4';
            app.Button_12 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '5';
            app.Button_13 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '6';
            app.Button_14 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '7';
            app.Button_16 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '8';
            app.Button_15 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '9';
            app.Button_17 = uibutton(app.TexttoMorseTab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '0';
            app.SPACEButton = uibutton(app.TexttoMorseTab, 'push');
            app.SPACEButton.ButtonPushedFcn = createCallbackFcn(app, @SPACEButtonPushed, true);
            app.SPACEButton.Text = 'SPACE';
            app.PlayMorseCodeLabel_2 = uilabel(app.TexttoMorseTab);
            app.PlayMorseCodeLabel_2.Text = 'Play Morse Code:';
            app.MorseCodeChartTab = uitab(app.TabGroup);
            app.MorseCodeChartTab.Title = 'Morse Code Chart';
            app.AButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.AButton_2.ButtonPushedFcn = createCallbackFcn(app, @AButton_2Pushed, true);
            app.AButton_2.Text = 'A';
            app.BButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.BButton_2.ButtonPushedFcn = createCallbackFcn(app, @BButton_2Pushed, true);
            app.BButton_2.Text = 'B';
            app.CButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C';
            app.DButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.DButton_2.ButtonPushedFcn = createCallbackFcn(app, @DButton_2Pushed, true);
            app.DButton_2.Text = 'D';
            app.EButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.EButton_2.ButtonPushedFcn = createCallbackFcn(app, @EButton_2Pushed, true);
            app.EButton_2.Text = 'E';
            app.FButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.FButton_2.ButtonPushedFcn = createCallbackFcn(app, @FButton_2Pushed, true);
            app.FButton_2.Text = 'F';
            app.Label = uilabel(app.MorseCodeChartTab);
            app.Label.Text = '• ━';
            app.Label_2 = uilabel(app.MorseCodeChartTab);
            app.Label_2.Text = '━ • • • ';
            app.Label_3 = uilabel(app.MorseCodeChartTab);
            app.Label_3.Text = '━ • ━ •';
            app.Label_4 = uilabel(app.MorseCodeChartTab);
            app.Label_4.Text = '━ • • ';
            app.Label_5 = uilabel(app.MorseCodeChartTab);
            app.Label_5.Text = '• ';
            app.Label_6 = uilabel(app.MorseCodeChartTab);
            app.Label_6.Text = '• • ━ •';
            app.GButton_3 = uibutton(app.MorseCodeChartTab, 'push');
            app.GButton_3.ButtonPushedFcn = createCallbackFcn(app, @GButton_3Pushed, true);
            app.GButton_3.Text = 'G';
            app.Label_7 = uilabel(app.MorseCodeChartTab);
            app.Label_7.Text = '━ ━ •';
            app.HButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.HButton_2.ButtonPushedFcn = createCallbackFcn(app, @HButton_2Pushed, true);
            app.HButton_2.Text = 'H';
            app.IButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.IButton_2.ButtonPushedFcn = createCallbackFcn(app, @IButton_2Pushed, true);
            app.IButton_2.Text = 'I';
            app.JButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.JButton_2.ButtonPushedFcn = createCallbackFcn(app, @JButton_2Pushed, true);
            app.JButton_2.Text = 'J';
            app.KButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.KButton_2.ButtonPushedFcn = createCallbackFcn(app, @KButton_2Pushed, true);
            app.KButton_2.Text = 'K';
            app.LButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.LButton_2.ButtonPushedFcn = createCallbackFcn(app, @LButton_2Pushed, true);
            app.LButton_2.Text = 'L';
            app.MButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.MButton_2.ButtonPushedFcn = createCallbackFcn(app, @MButton_2Pushed, true);
            app.MButton_2.Text = 'M';
            app.NButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.NButton_2.ButtonPushedFcn = createCallbackFcn(app, @NButton_2Pushed, true);
            app.NButton_2.Text = 'N';
            app.OButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.OButton_2.ButtonPushedFcn = createCallbackFcn(app, @OButton_2Pushed, true);
            app.OButton_2.Text = 'O';
            app.PButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Text = 'P';
            app.QButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.QButton_2.ButtonPushedFcn = createCallbackFcn(app, @QButton_2Pushed, true);
            app.QButton_2.Text = 'Q';
            app.RButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'R';
            app.SButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.SButton_2.ButtonPushedFcn = createCallbackFcn(app, @SButton_2Pushed, true);
            app.SButton_2.Text = 'S';
            app.TButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.TButton_2.ButtonPushedFcn = createCallbackFcn(app, @TButton_2Pushed, true);
            app.TButton_2.Text = 'T';
            app.UButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.UButton_2.ButtonPushedFcn = createCallbackFcn(app, @UButton_2Pushed, true);
            app.UButton_2.Text = 'U';
            app.VButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.VButton_2.ButtonPushedFcn = createCallbackFcn(app, @VButton_2Pushed, true);
            app.VButton_2.Text = 'V';
            app.WButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.WButton_2.ButtonPushedFcn = createCallbackFcn(app, @WButton_2Pushed, true);
            app.WButton_2.Text = 'W';
            app.XButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X';
            app.YButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y';
            app.ZButton_2 = uibutton(app.MorseCodeChartTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z';
            app.Button_19 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '1';
            app.Button_20 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '2';
            app.Button_21 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '3';
            app.Button_22 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '4';
            app.Button_23 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '5';
            app.Button_24 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '6';
            app.Button_25 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '7';
            app.Button_26 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '8';
            app.Button_27 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '9';
            app.Label_8 = uilabel(app.MorseCodeChartTab);
            app.Label_8.Text = '• • • •';
            app.Label_9 = uilabel(app.MorseCodeChartTab);
            app.Label_9.Text = '• •';
            app.Label_10 = uilabel(app.MorseCodeChartTab);
            app.Label_10.Text = '• ━ ━ ━ ';
            app.Label_11 = uilabel(app.MorseCodeChartTab);
            app.Label_11.Text = '━ • ━';
            app.Label_12 = uilabel(app.MorseCodeChartTab);
            app.Label_12.Text = '• ━ • •';
            app.Label_13 = uilabel(app.MorseCodeChartTab);
            app.Label_13.Text = '━ ━';
            app.Label_14 = uilabel(app.MorseCodeChartTab);
            app.Label_14.Text = '━ •';
            app.Label_15 = uilabel(app.MorseCodeChartTab);
            app.Label_15.Text = '━ ━ ━';
            app.Label_16 = uilabel(app.MorseCodeChartTab);
            app.Label_16.Text = '• ━ ━ •';
            app.Label_17 = uilabel(app.MorseCodeChartTab);
            app.Label_17.Text = '━ ━ • ━ ';
            app.Label_18 = uilabel(app.MorseCodeChartTab);
            app.Label_18.Text = '• ━ •';
            app.Label_19 = uilabel(app.MorseCodeChartTab);
            app.Label_19.Text = '• • •';
            app.Label_20 = uilabel(app.MorseCodeChartTab);
            app.Label_20.Text = '━';
            app.Label_21 = uilabel(app.MorseCodeChartTab);
            app.Label_21.Text = '• • ━';
            app.Label_22 = uilabel(app.MorseCodeChartTab);
            app.Label_22.Text = '• • • ━';
            app.Label_23 = uilabel(app.MorseCodeChartTab);
            app.Label_23.Text = '• ━ ━';
            app.Label_24 = uilabel(app.MorseCodeChartTab);
            app.Label_24.Text = '━ • • ━';
            app.Label_25 = uilabel(app.MorseCodeChartTab);
            app.Label_25.Text = '━ • ━ ━';
            app.Label_26 = uilabel(app.MorseCodeChartTab);
            app.Label_26.Text = '━ ━ • •';
            app.Label_28 = uilabel(app.MorseCodeChartTab);
            app.Label_28.Text = '• ━ ━ ━ ━';
            app.Label_29 = uilabel(app.MorseCodeChartTab);
            app.Label_29.Text = '• • ━ ━ ━ ';
            app.Label_30 = uilabel(app.MorseCodeChartTab);
            app.Label_30.Text = '• • • ━ ━';
            app.Label_31 = uilabel(app.MorseCodeChartTab);
            app.Label_31.Text = '• • • • ━ ';
            app.Label_32 = uilabel(app.MorseCodeChartTab);
            app.Label_32.Text = '• • • • •';
            app.Label_33 = uilabel(app.MorseCodeChartTab);
            app.Label_33.Text = '━ • • • • ';
            app.Label_34 = uilabel(app.MorseCodeChartTab);
            app.Label_34.Text = '━ ━ • • • ';
            app.Label_35 = uilabel(app.MorseCodeChartTab);
            app.Label_35.Text = '━ ━ ━ • • ';
            app.Label_36 = uilabel(app.MorseCodeChartTab);
            app.Label_36.Text = '━ ━ ━ ━ • ';
            app.Button_28 = uibutton(app.MorseCodeChartTab, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '0';
            app.Label_37 = uilabel(app.MorseCodeChartTab);
            app.Label_37.Text = '━ ━ ━ ━ ━ ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorseCodeTranslator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end